classdef Distribucion_cargas_lineales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PosicionYinicialGlobuloEditField  matlab.ui.control.NumericEditField
        PosicionYinicialGlobuloEditFieldLabel  matlab.ui.control.Label
        PosicionXinicialGlobuloEditField  matlab.ui.control.NumericEditField
        PosicionXinicialGlobuloEditFieldLabel  matlab.ui.control.Label
        PosicionYinicialcargaspositivasEditField  matlab.ui.control.NumericEditField
        PosicionYinicialcargaspositivasEditFieldLabel  matlab.ui.control.Label
        DistanciaentreejesEditField    matlab.ui.control.NumericEditField
        DistanciaentreejesEditFieldLabel  matlab.ui.control.Label
        PosicionXinicialcargaspositivasEditField  matlab.ui.control.NumericEditField
        PosicionXinicialcargaspositivasEditFieldLabel  matlab.ui.control.Label
        DistanciaentrecargasEditField  matlab.ui.control.NumericEditField
        DistanciaentrecargasEditFieldLabel  matlab.ui.control.Label
        MagnitudCargaGlobuloRojoEditField  matlab.ui.control.NumericEditField
        MagnitudCargaGlobuloRojoLabel  matlab.ui.control.Label
        MagnitudCargaLineasparalelaasEditField  matlab.ui.control.NumericEditField
        MagnitudCargaLineasparalelaasEditFieldLabel  matlab.ui.control.Label
        Cambiar_eje                    matlab.ui.control.Switch
        CambiardeEjeLabel              matlab.ui.control.Label
        Simular                        matlab.ui.control.Button
        Graficar                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simular
        function SimularButtonPushed(app, event)
            %Equipo 5
            %César Emilio Hernández Del Cid - A00835568 
            %Juan Jose Jauregui - A00836722
            %Danaé Sánchez Gutiérrez - A00836760
            %Abdiel Fritsche Barajas - A01234933
            %Hugo Castillo - A00836025
       

            Ex=0;
            Ey=0;
            Ex1=0;
            Ey1=0;
            ke=8.99*10^9;
            Qp=app.MagnitudCargaLineasparalelaasEditField.Value;
            Qn=Qp*-1;
            eje=string(app.Cambiar_eje.Value);
            posXiCargaP = app.PosicionXinicialcargaspositivasEditField.Value;
            posYiCargaP = app.PosicionYinicialcargaspositivasEditField.Value;
            cargas_Cantidad = 100;
            dist_CargasG = app.DistanciaentrecargasEditField.Value;
            dist_entre_cargas_PyN= app.DistanciaentreejesEditField.Value;


            if eje == "Eje Y"
               %definicion de posicion de las cargas       
               centrado = ((cargas_Cantidad*dist_CargasG)-(cargas_Cantidad*(dist_CargasG/2)))/2;
               posXiCargaN = posXiCargaP+dist_entre_cargas_PyN;
               posYiCargaN = centrado+posYiCargaP;  
            
                %generacion del campo de puntos (X,Y)
                minX = posXiCargaP-30; maxX=posXiCargaN+30;
                minY = posYiCargaP-30; maxY=posYiCargaP+(dist_CargasG*cargas_Cantidad)+30;
                d = .5;
                x = minX:d:maxX;
                y = minY:d:maxY;
                [Cx,Cy] = meshgrid(x,y);

                %ciclos para generar los calculos del campo electrico a partir de
                %la posicion individual de cada carga y sumandose
                for i = 0:cargas_Cantidad
                    Rx = Cx-posXiCargaN;
                    Ry = Cy-(posYiCargaN+((dist_CargasG/2)*i));
                    R = sqrt(Rx.^2+Ry.^2);
                    Ex = Ex+(ke.*Qn).*(Rx./R.^3);
                    Ey = Ey+(ke.*Qn).*(Ry./R.^3);
                end

                for i = 0:cargas_Cantidad
                    Rx2 = Cx-posXiCargaP;
                    Ry2 = Cy-(posYiCargaP+(dist_CargasG*i));
                    R2 = sqrt(Rx2.^2+Ry2.^2);
                    Ex1 = Ex1 + ((ke.*Qp).*(Rx2./R2.^3));
                    Ey1 = Ey1+ ((ke.*Qp).*(Ry2./R2.^3));
                end

            elseif eje =="Eje X"
                %definicion de posicion de las cargas       

                centrado = ((cargas_Cantidad*dist_CargasG)-(cargas_Cantidad*(dist_CargasG/2)))/2;
                posXiCargaN = centrado+posXiCargaP;
                posYiCargaN = posYiCargaP+dist_entre_cargas_PyN;
        
                minX = posXiCargaP-30; maxX=posXiCargaP+(dist_CargasG*cargas_Cantidad)+30;
                minY = posYiCargaP-30; maxY=posYiCargaN+30;
                d = .5;
                x = minX:d:maxX;
                y = minY:d:maxY;
                [Cx,Cy] = meshgrid(x,y);

                %ciclos para generar los calculos del campo electrico a partir de
                %la posicion individual de cada carga y sumandose
                for i = 0:cargas_Cantidad
                    Rx = Cx-(posXiCargaN+((dist_CargasG/2)*i));
                    Ry = Cy-posYiCargaN;
                    R = sqrt(Rx.^2+Ry.^2);
                    Ex = Ex+(ke.*Qn).*(Rx./R.^3);
                    Ey = Ey+(ke.*Qn).*(Ry./R.^3);
                end

                for i = 0:cargas_Cantidad
                    Rx2 = Cx-(posXiCargaP+(dist_CargasG*i));
                    Ry2 = Cy-posYiCargaP;
                    R2 = sqrt(Rx2.^2+Ry2.^2);
                    Ex1 = Ex1 + ((ke.*Qp).*(Rx2./R2.^3));
                    Ey1 = Ey1+ ((ke.*Qp).*(Ry2./R2.^3));
                end

            end

            EyT = Ey+Ey1;
            ExT = Ex+Ex1;
            E =  sqrt(ExT.^2+EyT.^2);
            U = ExT./E; V = EyT./E; 

            posX_globulos = app.PosicionXinicialGlobuloEditField.Value;
            posY_globulos = app.PosicionYinicialGlobuloEditField.Value;
            q_globulo = app.MagnitudCargaGlobuloRojoEditField.Value;
            masa_globulo = 2.5e-14; 
         
            %ancho de la carga en el grafico
            a = 0.15;
            hold on
            quiver(app.Graficar,Cx,Cy,U,V,Color='cyan',LineWidth=1.5,AutoScaleFactor=0.8);
            xlabel(app.Graficar,'Campo electrico componente X') 
            ylabel(app.Graficar,'Campo electrico componente Y')
            globulo = rectangle(app.Graficar,'Position',[posX_globulos,posY_globulos,.25,.25], ...
                            'FaceColor','black','EdgeColor','red' ,'Curvature',[1,1]);

            if eje == "Eje Y"
                title(app.Graficar,'Dipolo Electrico'); axis equal
                for i = 0:cargas_Cantidad
                     rectangle(app.Graficar,'Position',[posXiCargaP-a/2,(posYiCargaP+(dist_CargasG*i))-a/2,a,a], ...
                         'Curvature',[1,1],'FaceColor','red','EdgeColor','red');
                     rectangle(app.Graficar,'Position',[posXiCargaN-a/2,(posYiCargaN+(dist_CargasG/2)*i)-a/2,a,a], ...
                         'Curvature',[1,1],'FaceColor','blue','EdgeColor','blue');
                end
            elseif eje == "Eje X"
                title(app.Graficar,'Dipolo Electrico'); axis equal
                %ciclo para la graficacion de todas las cargas en el grafico generado
                %por la posicion (X,Y)
                for i = 0:cargas_Cantidad
                    rectangle(app.Graficar,'Position',[posXiCargaP+(dist_CargasG*i)-a/2,posYiCargaP-a/2,a,a], ...
                                     'Curvature',[1,1],'FaceColor','red','EdgeColor','red');
                    rectangle(app.Graficar,'Position',[posXiCargaN+((dist_CargasG/2)*i)-a/2, ...
                                     posYiCargaN-a/2,a,a],'Curvature',[1,1],'FaceColor','blue','EdgeColor','blue'); 
                end
            end
            
            for i = 0.00001:0.00001:3
                    if eje == "Eje Y"
                         if  (posX_globulos == posXiCargaP || posX_globulos == posXiCargaN) &&...      
                             ((posY_globulos>=posYiCargaP&&posY_globulos<=posYiCargaP+(dist_CargasG*100)) ||...
                             (posY_globulos>=posYiCargaN&&posY_globulos<=posYiCargaN+(dist_CargasG/2*100)))
                                   globulo = rectangle(app.Graficar,'Position',[posX_globulos,posY_globulos,.40,.40], ...
                                   'FaceColor','black','EdgeColor','red' ,'Curvature',[1,1]);
                                   drawnow;     
                             break;
                         else
                                pause(0.1)
                               
                                ex = interp2(Cx, Cy, ExT, posX_globulos, posY_globulos);
                                ey = interp2(Cx, Cy, EyT, posX_globulos, posY_globulos);
                                FeX = q_globulo*ex;
                                FeY = q_globulo*ey;
                                ay = FeY/masa_globulo;
                                ax = FeX/masa_globulo;
                                distx = 0.5*ax*(i.^2);  
                                disty = 0.5*ay*(i.^2);

                                if (isfinite(distx) == false || isnumeric(distx) == false)  &&...
                                    (isfinite(disty) == false || isnumeric(distay) == false)                                       
                                    break;

                                elseif (isfinite(distx) == true && isnumeric(distx) == true)  &&...
                                          (isfinite(disty) == true && isnumeric(distx) == true)
                                                delete(globulo)
                                                posY_globulos = posY_globulos + disty;
                                                posX_globulos = posX_globulos + distx;
                                                globulo = rectangle(app.Graficar,'Position',[posX_globulos,posY_globulos,.40,.40], ...
                                                            'FaceColor','black','EdgeColor','red' ,'Curvature',[1,1]);
                                                drawnow
                                end
                         end
                    elseif eje == "Eje X"
                           if  (posY_globulos == posYiCargaP || posY_globulos == posYiCargaN) &&...      
                               ((posX_globulos>=posXiCargaP && posX_globulos<=posXiCargaP+(dist_CargasG*100)) ||...
                               (posX_globulos>=posXiCargaN && posX_globulos<=posXiCargaN+(dist_CargasG/2*100)))
                                   globulo = rectangle(app.Graficar,'Position',[posX_globulos,posY_globulos,.40,.40], ...
                                                              'FaceColor','black','EdgeColor','red' ,'Curvature',[1,1]);
                                   drawnow;       
                                   break;
                           else
                                pause(0.1)
                                
                                ex = interp2(Cx, Cy, ExT, posX_globulos, posY_globulos);
                                ey = interp2(Cx, Cy, EyT, posX_globulos, posY_globulos);
                                FeX = q_globulo*ex;
                                FeY = q_globulo*ey;
                                ay = FeY/masa_globulo;
                                ax = FeX/masa_globulo;
                                distx = 0.5*ax*(i.^2);  
                                disty = 0.5*ay*(i.^2);
                               
                                if (isfinite(distx) == false || isnumeric(distx) == false)  &&...
                                        (isfinite(disty) == false || isnumeric(distay) == false)   
                                    break;
                                    
                                elseif (isfinite(distx) == true && isnumeric(distx) == true)  &&...
                                          (isfinite(disty) == true && isnumeric(distx) == true)
                                            delete(globulo)
                                            posY_globulos = posY_globulos + disty;
                                            posX_globulos = posX_globulos + distx;
                                            globulo = rectangle(app.Graficar,'Position',[posX_globulos,posY_globulos,.40,.40], ...
                                                          'FaceColor','black','EdgeColor','red' ,'Curvature',[1,1]);
                                            drawnow;
                                end
                           end
                    end      
            end                       
            hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graficar = uiaxes(app.UIFigure);
            title(app.Graficar, 'Distribucion cargas lineales')
            xlabel(app.Graficar, 'Campo electrico eje X')
            ylabel(app.Graficar, 'Campo electrico eje Y')
            app.Graficar.XTick = [];
            app.Graficar.YTick = [];
            app.Simular = uibutton(app.UIFigure, 'push');
            app.Simular.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.Simular.Text = 'Simular';
            app.CambiardeEjeLabel = uilabel(app.UIFigure);
            app.CambiardeEjeLabel.Text = 'Cambiar de Eje';
            app.Cambiar_eje = uiswitch(app.UIFigure, 'slider');
            app.Cambiar_eje.Items = {'Eje Y', 'Eje X'};
            app.Cambiar_eje.Value = 'Eje Y';
            app.MagnitudCargaLineasparalelaasEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitudCargaLineasparalelaasEditFieldLabel.Text = 'Magnitud Carga Lineas paralelaas';
            app.MagnitudCargaLineasparalelaasEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnitudCargaGlobuloRojoLabel = uilabel(app.UIFigure);
            app.MagnitudCargaGlobuloRojoLabel.Text = 'Magnitud Carga Globulo Rojo';
            app.MagnitudCargaGlobuloRojoEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaentrecargasEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaentrecargasEditFieldLabel.Text = 'Distancia entre cargas';
            app.DistanciaentrecargasEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXinicialcargaspositivasEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionXinicialcargaspositivasEditFieldLabel.Text = 'Posicion X inicial cargas positivas';
            app.PosicionXinicialcargaspositivasEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaentreejesEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaentreejesEditFieldLabel.Text = 'Distancia entre ejes';
            app.DistanciaentreejesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYinicialcargaspositivasEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionYinicialcargaspositivasEditFieldLabel.Text = 'Posicion Y inicial cargas positivas';
            app.PosicionYinicialcargaspositivasEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXinicialGlobuloEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionXinicialGlobuloEditFieldLabel.Text = 'Posicion X inicial Globulo';
            app.PosicionXinicialGlobuloEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYinicialGlobuloEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionYinicialGlobuloEditFieldLabel.Text = 'Posicion Y inicial Globulo ';
            app.PosicionYinicialGlobuloEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Distribucion_cargas_lineales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end